classdef HIRSCHLAB_JEM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        JEMGeneratorPanel             matlab.ui.container.Panel
        VersionEditFieldLabel         matlab.ui.control.Label
        VersionEditField              matlab.ui.control.EditField
        ElectrodeIDDropDownLabel      matlab.ui.control.Label
        ElectrodeIDDropDown           matlab.ui.control.DropDown
        ResearcherDropDownLabel       matlab.ui.control.Label
        ResearcherDropDown            matlab.ui.control.DropDown
        RecordingDateDatePickerLabel  matlab.ui.control.Label
        RecordingDateDatePicker       matlab.ui.control.DatePicker
        SpeciesDropDownLabel          matlab.ui.control.Label
        SpeciesDropDown               matlab.ui.control.DropDown
        SubjectIDEditFieldLabel       matlab.ui.control.Label
        SubjectIDEditField            matlab.ui.control.EditField
        BregmaCoordinatesLabel        matlab.ui.control.Label
        XEditFieldLabel               matlab.ui.control.Label
        XEditField                    matlab.ui.control.NumericEditField
        YEditFieldLabel               matlab.ui.control.Label
        YEditField                    matlab.ui.control.NumericEditField
        PenatrationLabel              matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        AddButton                     matlab.ui.control.Button
        EditButton                    matlab.ui.control.Button
        ExportJEMButton               matlab.ui.control.Button
        LoadJEMButton                 matlab.ui.control.Button
        RemoveButton_3                matlab.ui.control.Button
        SessionEditorPanel            matlab.ui.container.Panel
        PenatrationIDLabel            matlab.ui.control.Label
        Penatration_IDLabel           matlab.ui.control.Label
        UITable2                      matlab.ui.control.Table
        AddButton_2                   matlab.ui.control.Button
        RemoveButton_2                matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        selectedPenRow = false; % Description
        selectedSesRow = false;
        sessionInfo = {}; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            if isa(app.UITable.Data, 'table')
                tmp = app.UITable.Data;
                app.UITable.Data = [tmp; {height(tmp)+1, 0, 0, 0, 0, 0, ''}];
            else
                tmp = cell2table({1, 0, 0, 0, 0, 0, ''});
                tmp.Properties.VariableNames = {'Index', 'X', 'Y', 'Z', 'Quality', 'Sessions', 'Notes'};
                app.UITable.Data = tmp;
            end
            app.sessionInfo{end+1} = table({}, {}, {}, {}, 'VariableNames',{'Index','Type','Quality','Notes'});
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)

            selectedRow = event.Indices(1);
            % selectedCol = eventdata.Indices(2);
            app.selectedPenRow = selectedRow;
        end

        % Button pushed function: RemoveButton_3
        function RemoveButton_3Pushed(app, event)
            if app.selectedPenRow
                app.UITable.Data(app.selectedPenRow, :) = [];
                app.sessionInfo(app.selectedPenRow) = [];
                app.selectedPenRow = false;
                app.UITable.Data.Index = (1:height(app.UITable.Data))';
            end
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            if app.selectedPenRow
                target_session = app.UITable.Data(app.selectedPenRow, :);
                app.Penatration_IDLabel.Text = sprintf('P%03d', target_session.Index);
                app.UITable2.Data = app.sessionInfo{app.selectedPenRow};
            end
        end

        % Button pushed function: AddButton_2
        function AddButton_2Pushed(app, event)
            if isa(app.UITable2.Data, 'table')
                tmp = app.UITable2.Data;
                try
                    app.UITable2.Data = [tmp; {tmp.Index{end}+1, '', 0, ''}];
                catch
                    app.UITable2.Data = [tmp; {1, '', 0, ''}];
                end
            else
                tmp = cell2table({1, '', 0, ''});
                tmp.Properties.VariableNames = {'Index', 'Type', 'Quality', 'Notes'};
                app.UITable2.Data = tmp;
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.sessionInfo{app.selectedPenRow} = app.UITable2.Data;
            app.UITable.Data{app.selectedPenRow, 6} = height(app.UITable2.Data);
        end

        % Button pushed function: RemoveButton_2
        function RemoveButton_2Pushed(app, event)
            if app.selectedSesRow
                app.UITable2.Data(app.selectedSesRow, :) = [];
                app.selectedSesRow = false;
            end
        end

        % Cell selection callback: UITable2
        function UITable2CellSelection(app, event)
            selectedRow = event.Indices(1);
            % selectedCol = eventdata.Indices(2);
            app.selectedSesRow = selectedRow;
        end

        % Button pushed function: ExportJEMButton
        function ExportJEMButtonPushed(app, event)
            export_dir = uigetdir;
            if export_dir ~= 0
                pen_data = table2struct(app.UITable.Data);
                for idx = 1:length(pen_data)
                    pen_data(idx).Sessions = table2struct(app.sessionInfo{idx});
                end
                output_data = struct('version', app.VersionEditField.Value, ...
                    'Electrode_ID', app.ElectrodeIDDropDown.Value,...
                    'Researcher', app.ResearcherDropDown.Value, ...
                    'Recording_Date', datestr(app.RecordingDateDatePicker.Value, 'yyyy-mm-dd'),...
                    'Subject_ID', app.SubjectIDEditField.Value, ...
                    'Species', app.SpeciesDropDown.Value,...
                    'Bregma_Coord', struct('X', app.XEditField.Value, 'Y', app.YEditField.Value), ...
                    'Penatration', pen_data);
                fileID = fopen(fullfile(export_dir, 'JEM.json'), 'w');
                fprintf(fileID, jsonencode(output_data));
                fclose(fileID);
            end
        end

        % Button pushed function: LoadJEMButton
        function LoadJEMButtonPushed(app, event)
            [fname, fpath] = uigetfile('*.json');
            fid = fopen(fullfile(fpath, fname));
            jem_data = jsondecode(fread(fid, '*char')');
            fclose(fid);
            app.VersionEditField.Value = jem_data.version;
            app.ElectrodeIDDropDown.Value = jem_data.Electrode_ID;
            app.ResearcherDropDown.Value = jem_data.Researcher;
            app.RecordingDateDatePicker.Value = datetime(jem_data.Recording_Date, 'InputFormat', 'yyyy-MM-dd');
            app.SubjectIDEditField.Value = jem_data.Subject_ID;
            app.SpeciesDropDown.Value = jem_data.Species;
            app.XEditField.Value = jem_data.Bregma_Coord.X;
            app.YEditField.Value = jem_data.Bregma_Coord.Y;
            
            app.sessionInfo = {};
            for idx = 1:length(jem_data.Penatration)
                if ~isempty(jem_data.Penatration(idx).Sessions)
                    app.sessionInfo{end+1} = struct2table(jem_data.Penatration(idx).Sessions);
                else
                    app.sessionInfo{end+1} = table({}, {}, {}, {}, 'VariableNames',{'Index','Type','Quality','Notes'});
                end
                jem_data.Penatration(idx).Sessions = length(jem_data.Penatration(idx).Sessions);
            end
            
            app.UITable.Data = struct2table(jem_data.Penatration);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.JEMGeneratorPanel = uipanel(app.UIFigure);
            app.JEMGeneratorPanel.Title = 'JEM Generator';
            app.VersionEditFieldLabel = uilabel(app.JEMGeneratorPanel);
            app.VersionEditFieldLabel.Text = 'Version';
            app.VersionEditField = uieditfield(app.JEMGeneratorPanel, 'text');
            app.VersionEditField.Editable = 'off';
            app.VersionEditField.Value = '0.1.0';
            app.ElectrodeIDDropDownLabel = uilabel(app.JEMGeneratorPanel);
            app.ElectrodeIDDropDownLabel.Text = 'Electrode ID';
            app.ElectrodeIDDropDown = uidropdown(app.JEMGeneratorPanel);
            app.ElectrodeIDDropDown.Items = {'-', 'H6B_1430', 'H6B_5332'};
            app.ElectrodeIDDropDown.Value = '-';
            app.ResearcherDropDownLabel = uilabel(app.JEMGeneratorPanel);
            app.ResearcherDropDownLabel.Text = 'Researcher';
            app.ResearcherDropDown = uidropdown(app.JEMGeneratorPanel);
            app.ResearcherDropDown.Items = {'-', 'Yizhan Miao', 'Alexis Gorin', 'Judith Hirsch'};
            app.ResearcherDropDown.Value = '-';
            app.RecordingDateDatePickerLabel = uilabel(app.JEMGeneratorPanel);
            app.RecordingDateDatePickerLabel.Text = 'Recording Date';
            app.RecordingDateDatePicker = uidatepicker(app.JEMGeneratorPanel);
            app.SpeciesDropDownLabel = uilabel(app.JEMGeneratorPanel);
            app.SpeciesDropDownLabel.Text = 'Species';
            app.SpeciesDropDown = uidropdown(app.JEMGeneratorPanel);
            app.SpeciesDropDown.Items = {'Mouse', 'Rat', 'Ferret', 'Cat'};
            app.SpeciesDropDown.Value = 'Mouse';
            app.SubjectIDEditFieldLabel = uilabel(app.JEMGeneratorPanel);
            app.SubjectIDEditFieldLabel.Text = 'Subject ID';
            app.SubjectIDEditField = uieditfield(app.JEMGeneratorPanel, 'text');
            app.BregmaCoordinatesLabel = uilabel(app.JEMGeneratorPanel);
            app.BregmaCoordinatesLabel.Text = 'Bregma Coordinates';
            app.XEditFieldLabel = uilabel(app.JEMGeneratorPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.JEMGeneratorPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.JEMGeneratorPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.JEMGeneratorPanel, 'numeric');
            app.PenatrationLabel = uilabel(app.JEMGeneratorPanel);
            app.PenatrationLabel.Text = 'Penatration';
            app.UITable = uitable(app.JEMGeneratorPanel);
            app.UITable.ColumnName = {'Index'; 'X'; 'Y'; 'Z'; 'Quality'; 'Sessions'; 'Notes'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true true true true false true];
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.AddButton = uibutton(app.JEMGeneratorPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.EditButton = uibutton(app.JEMGeneratorPanel, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.ExportJEMButton = uibutton(app.JEMGeneratorPanel, 'push');
            app.ExportJEMButton.ButtonPushedFcn = createCallbackFcn(app, @ExportJEMButtonPushed, true);
            app.ExportJEMButton.Text = 'Export JEM';
            app.LoadJEMButton = uibutton(app.JEMGeneratorPanel, 'push');
            app.LoadJEMButton.ButtonPushedFcn = createCallbackFcn(app, @LoadJEMButtonPushed, true);
            app.LoadJEMButton.Text = 'Load JEM';
            app.RemoveButton_3 = uibutton(app.JEMGeneratorPanel, 'push');
            app.RemoveButton_3.ButtonPushedFcn = createCallbackFcn(app, @RemoveButton_3Pushed, true);
            app.RemoveButton_3.Text = 'Remove';
            app.SessionEditorPanel = uipanel(app.UIFigure);
            app.SessionEditorPanel.Title = 'Session Editor';
            app.PenatrationIDLabel = uilabel(app.SessionEditorPanel);
            app.PenatrationIDLabel.Text = 'Penatration ID:';
            app.Penatration_IDLabel = uilabel(app.SessionEditorPanel);
            app.Penatration_IDLabel.Text = 'Penatration_ID';
            app.UITable2 = uitable(app.SessionEditorPanel);
            app.UITable2.ColumnName = {'Index'; 'Type'; 'Quality'; 'Notes'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = true;
            app.UITable2.CellSelectionCallback = createCallbackFcn(app, @UITable2CellSelection, true);
            app.AddButton_2 = uibutton(app.SessionEditorPanel, 'push');
            app.AddButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddButton_2Pushed, true);
            app.AddButton_2.Text = 'Add';
            app.RemoveButton_2 = uibutton(app.SessionEditorPanel, 'push');
            app.RemoveButton_2.ButtonPushedFcn = createCallbackFcn(app, @RemoveButton_2Pushed, true);
            app.RemoveButton_2.Text = 'Remove';
            app.SaveButton = uibutton(app.SessionEditorPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HIRSCHLAB_JEM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end